classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportMenu                      matlab.ui.container.Menu
        SaveContourMenu                 matlab.ui.container.Menu
        SaveProfileMenu                 matlab.ui.container.Menu
        SaveDomainImage                 matlab.ui.container.Menu
        PatchingMenu                    matlab.ui.container.Menu
        DBDonAirfoilMenu                matlab.ui.container.Menu
        FigurePanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        CurvePlottingTab                matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        DataList                        matlab.ui.control.ListBox
        AvailableDataLabel              matlab.ui.control.Label
        FoundQuantities                 matlab.ui.control.DropDown
        FoundQuantitiesDropDownLabel    matlab.ui.control.Label
        ReadDataPanelProfilePlottig     matlab.ui.container.Panel
        GetFolderP                      matlab.ui.control.Button
        DataFolderP                     matlab.ui.control.EditField
        DataFolderEditFieldLabel_2      matlab.ui.control.Label
        ContourPlottingTab              matlab.ui.container.Tab
        E_PlaceTile                     matlab.ui.control.Button
        TiledLayoutSettings             matlab.ui.container.Panel
        UsetiledlayoutCheckBox          matlab.ui.control.CheckBox
        E_TileNumberList                matlab.ui.control.DropDown
        E_TileNumberListLabel           matlab.ui.control.Label
        E_NumCols                       matlab.ui.control.NumericEditField
        E_NumRows                       matlab.ui.control.NumericEditField
        E_NumofRowsNColsLabel           matlab.ui.control.Label
        VectorFieldSettingsPanel        matlab.ui.container.Panel
        ColorCode_B                     matlab.ui.control.NumericEditField
        ColorCode_G                     matlab.ui.control.NumericEditField
        ColorCode_R                     matlab.ui.control.NumericEditField
        ColorRGBLabel                   matlab.ui.control.Label
        LineStyle                       matlab.ui.control.DropDown
        LineStyleDropDownLabel          matlab.ui.control.Label
        LineWidth                       matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        ScalingFactor                   matlab.ui.control.NumericEditField
        ScalingFactorEditFieldLabel     matlab.ui.control.Label
        AxisLabelsPanel                 matlab.ui.container.Panel
        TitleFontSizeEditField          matlab.ui.control.NumericEditField
        TitleFontSizeEditFieldLabel     matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        E_TileNumberDropDown            matlab.ui.control.DropDown
        E_TileNumberDropDownLabel       matlab.ui.control.Label
        YLabelFontSizeEditField         matlab.ui.control.NumericEditField
        XLabelFontSizeEditFieldLabel_2  matlab.ui.control.Label
        YLabel                          matlab.ui.control.EditField
        YLabelEditFieldLabel            matlab.ui.control.Label
        LabelInterpreter                matlab.ui.control.CheckBox
        XLabelFontSize                  matlab.ui.control.NumericEditField
        XLabelFontSizeEditFieldLabel    matlab.ui.control.Label
        XLabel                          matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        ScalarFieldSettingsPanel        matlab.ui.container.Panel
        ContourLines                    matlab.ui.control.CheckBox
        AddLabelsOnContourLines         matlab.ui.control.CheckBox
        ColorBarLocation                matlab.ui.control.DropDown
        ColorBarLocationDropDownLabel   matlab.ui.control.Label
        ColorBarInterpreter             matlab.ui.control.CheckBox
        ColorBarFontSize                matlab.ui.control.NumericEditField
        ColorBarFontSizeEditFieldLabel  matlab.ui.control.Label
        ColorBarString                  matlab.ui.control.EditField
        ColorBarStringEditFieldLabel    matlab.ui.control.Label
        ShadeLevel                      matlab.ui.control.NumericEditField
        ShadeLevelEditFieldLabel        matlab.ui.control.Label
        ColorMap                        matlab.ui.control.DropDown
        ColorMapDropDownLabel           matlab.ui.control.Label
        ClearFigureC                    matlab.ui.control.Button
        DisplayFigureC                  matlab.ui.control.Button
        VectorField                     matlab.ui.control.DropDown
        VectorFieldsDropDownLabel       matlab.ui.control.Label
        ScalarField                     matlab.ui.control.DropDown
        ScalarFieldsDropDownLabel       matlab.ui.control.Label
        ReadDataPanel                   matlab.ui.container.Panel
        DataFolderC                     matlab.ui.control.EditField
        DataFolderEditFieldLabel        matlab.ui.control.Label
        GetFolderC                      matlab.ui.control.Button
        TUICommandTab                   matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        WriteCommandsButton             matlab.ui.control.Button
        DrawDomainButton                matlab.ui.control.Button
        DomainParametersforRectangularAreaPanel  matlab.ui.container.Panel
        DrawarectangularregionCheckBox  matlab.ui.control.CheckBox
        NumofColumnsEditField           matlab.ui.control.NumericEditField
        NumofColumnsEditFieldLabel      matlab.ui.control.Label
        NumofRowsEditField              matlab.ui.control.NumericEditField
        NumofRowsEditFieldLabel         matlab.ui.control.Label
        VerticalminEditField            matlab.ui.control.NumericEditField
        VerticalminEditFieldLabel       matlab.ui.control.Label
        VerticalmaxEditField            matlab.ui.control.NumericEditField
        VerticalmaxEditFieldLabel       matlab.ui.control.Label
        HorizontalminEditField          matlab.ui.control.NumericEditField
        HorizontalminEditFieldLabel     matlab.ui.control.Label
        HorizontalmaxEditField          matlab.ui.control.NumericEditField
        HorizontalmaxEditFieldLabel     matlab.ui.control.Label
        TUICommandsTextArea             matlab.ui.control.TextArea
        TUICommandsTextAreaLabel        matlab.ui.control.Label
        SavingOptionsPanel              matlab.ui.container.Panel
        GetFolderButton_2               matlab.ui.control.Button
        NodeValuesCheckBox              matlab.ui.control.CheckBox
        CaseName4TUI                    matlab.ui.control.EditField
        CaseNameEditFieldLabel          matlab.ui.control.Label
        SaveFolder4TUI                  matlab.ui.control.EditField
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        DataOptionsPanel                matlab.ui.container.Panel
        TemperatureCheckBox             matlab.ui.control.CheckBox
        MachNumberCheckBox              matlab.ui.control.CheckBox
        TurbulenceIntensityCheckBox     matlab.ui.control.CheckBox
        DensityCheckBox                 matlab.ui.control.CheckBox
        VelocityVector2DCheckBox        matlab.ui.control.CheckBox
        StaticPressureCheckBox          matlab.ui.control.CheckBox
        DomainParametersforNACA4digitairfoilPanel  matlab.ui.container.Panel
        Originycoordinate               matlab.ui.control.NumericEditField
        LeadingedgeycoordinateLabel     matlab.ui.control.Label
        Originxcoordinate               matlab.ui.control.NumericEditField
        OriginxcoordinateLabel          matlab.ui.control.Label
        WakeToChordRatio                matlab.ui.control.NumericEditField
        AirfoilChordLengthmLabel_3      matlab.ui.control.Label
        HeightToChordRatio              matlab.ui.control.NumericEditField
        AirfoilChordLengthmLabel_2      matlab.ui.control.Label
        AirfoilChordLength              matlab.ui.control.NumericEditField
        AirfoilChordLengthmLabel        matlab.ui.control.Label
        NumberofPointonLineEditField    matlab.ui.control.NumericEditField
        NumberofPointonLineEditFieldLabel  matlab.ui.control.Label
        AirfoilThicknessRatio           matlab.ui.control.NumericEditField
        AirfoilThicknessRatioEditFieldLabel  matlab.ui.control.Label
        NumOfPointsonAirfoil            matlab.ui.control.NumericEditField
        AirfoilChordLengthmLabel_5      matlab.ui.control.Label
        AirfoilMaxCamberLocation        matlab.ui.control.NumericEditField
        AirfoilMaxCamberLocationEditFieldLabel  matlab.ui.control.Label
        GrowthRate                      matlab.ui.control.NumericEditField
        AirfoilChordLengthmLabel_4      matlab.ui.control.Label
        AirfoilCamberRatio              matlab.ui.control.NumericEditField
        AirfoilCamberRatioEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %**** Properties for Domain Generation Section ****
        Yt                              % Thickness Function
        SlopeFunc                       % First Derivative of Thickness Function
        P1                              % Points on Airfoil
        P2                              % Points on the opposite side
        TUIcommand                      % Entire command for saving option
        AxesDomainGraph                 % Axes of the domain graph for manipulation of the graph
        
        % **** Properties for Contour Section ****
        xq                              % x-coordinates of the grid
        yq                              % y-coordinates of the grid
        uq                              % x-component of the property
        vq                              % y-component of the property
        ContourData
        GraphHandlesC = configureDictionary("string","cell")     % Dictionary containing graph details
        TileHandlesC = configureDictionary("double","cell")      % Dictionary containing tile details
        AxesContour

        % **** Properties for Profile Plot ****
        GraphHandles1 = configureDictionary("double","cell")     % Dictionary containing graph details
        TileHandles1 = configureDictionary("double","cell")      % Dictionary containing tile details
        ProfileData


    end
    
    methods (Access = private)
        %% Curve Plotting
        function ReadProfileData(app)
            warning('off','all')
            % Get the file names
            cd(app.DataFolderP.Value)
            Files = dir('*-typeP-*.xy');
            % Read the data
            AllData = configureDictionary("string","cell");
            for i = 1 : length(Files)
                % Abstract the case name 
                CN = regexp(Files(i).name, '^(.*)-type.', 'tokens', 'once');
                
                % Open the file
                fID = fopen(Files(i).name,"r");
                
                % Read the type of the quantity
                type = fgetl(fID);
                type = regexp(type,'"(.*?)"', 'tokens');
                
                % Start reading, curve names and its data
                data = configureDictionary("string","cell");
                searchif = true;
                while searchif
                    % Read two reductant lines
                    fgetl(fID);
                    fgetl(fID);
                    % Read the name of the curve
                    LineName = fgetl(fID);
                    % Check if the end of the file is reached
                    if LineName == - 1 
                        break
                    end
                    % Extract the name of the curve
                    LineName = regexp(LineName,'"(.*?)"', 'tokens');
                    % Read the data
                    LineData = textscan(fID,'%f %f',HeaderLines=1);
                    Location = LineData{1,1};
                    Value = LineData{1,2};
                    % Assign into the dictionary
                    data = insert(data,string(LineName{1}{1}) + " ("+ CN +")" ,{[Location Value]});
                end
                
                % Check if the quantity is read before
                quantities = string(keys(AllData));
                if find(quantities ==  string(type),1)
                    currentData = AllData(type);
                    currentData = currentData{1};
                    NewDataKeys = keys(data);
                    for n = 1:length(NewDataKeys)
                         currentData = insert(currentData,NewDataKeys{n}, data(NewDataKeys{n}));
                    end
                    AllData = insert(AllData,type{1}{1},{currentData});
                else
                    AllData = insert(AllData,type{1}{1},{data});
                end
                fclose(fID);
            end
            
            % Set the found data section
            app.ProfileData = AllData;
            
            list = keys(AllData,"cell");
            for i = 1 : length(list)
                list{i} = char(list{i});
            end
            app.FoundQuantities.Items = list;
            app.FoundQuantities.Value = list{1};

            DataToList = AllData(list{1});
            DataToList = DataToList{1};
            
            itemsDataList = keys(DataToList);
            for i = 1 : length(itemsDataList)
                itemsDataList{i} = char(itemsDataList{i});
            end
            app.DataList.Items = itemsDataList;
        end


        %% Scalar and Vectoral Field Display
        function ReadData(app,xFileName,yFileName)
            
            % Navigate to the folder which contains xy files
            cd(app.DataFolderC.Value)
            m = 10;

            % Open the files
            
           
            fIDx = fopen(xFileName,"r");
            fIDy = fopen(yFileName,"r");
            
            % Initialize variables containing the data
            UUq = cell(1,m); % x component of the property at the node
            VVq = cell(1,m); % y component of the property at the node
            XXq = cell(1,m); % x coordinate of the node
            YYq = cell(1,m); % y coordinate 
            
            % Start Reading
            find = true;
            typeX = textscan(fIDx,'(title "%s %s")'); % dummy information
            typeY = textscan(fIDx,'(title "%s %s")'); % dummy information
            while find
                        locx = textscan(fIDx,'((xy/key/label "rk%d"))',HeaderLines=2);
                        locy = textscan(fIDy,'((xy/key/label "rk%d"))',HeaderLines=2);
                        if isempty(locx{1});break;end
                        datax = textscan(fIDx,'%f %f',HeaderLines=1);
                        datay = textscan(fIDy,'%f %f',HeaderLines=1);
                        
                        UUq{locx{1}}= datax(2); 
                        XXq{locx{1}} = datax(1); 
                        
                        VVq{locy{1}}= datay(2); 
                        YYq{locy{1}}= datay(1);  
            end
            fclose all;
            app.xq = XXq;
            app.yq = YYq;
            app.uq = UUq;
            app.vq = VVq;
        end
        function ReFormatData(app)
            UUq = app.uq; %  x component of the property
            VVq = app.vq; %  y component of the property
            XXq = app.xq; %  x coordinate of the node
            YYq = app.yq; %  x coordinate of the node
    
            % Find the minimum number of rows
            verticalDimensions = zeros(1,length(UUq));
            for i = 1:length(UUq)
                verticalDimensions(i) = length(UUq{i}{1});
            end
            n = min(verticalDimensions); % number of rows
            m = length(UUq); % number of columns
            
            % Create empty matrices for reformated data
            RFxq =  zeros(n,m);
            RFyq =  zeros(n,m);
            RFuq =  zeros(n,m);
            RFvq =  zeros(n,m);
            
            % Reformat the data
            for column = 1:m
                for row = 1:n
                    RFxq(row,column) = XXq{column}{1}(row);
                    RFyq(row,column) = YYq{column}{1}(row);
                    RFuq(row,column) = UUq{column}{1}(row);
                    RFvq(row,column) = VVq{column}{1}(row);
                end
            end
    
            % Assign to the temporary place holder
            app.xq = RFxq;
            app.yq = RFyq;
            app.uq = RFuq;
            app.vq = RFvq;
        end
        function importContourData(app)
            cd(app.DataFolderC.Value)
            IDs = {"p", "V", "rho","M","TI","T"};
            PropertyNames = {'Static Pressure','Velocity Vector','Density','Mach Number','Turbulence Intensity','Temperature'};
            IsScalar = [1 0 1 1 1 1];
            ScalarItems = {'None'};
            VectorItems = {'None'};
            Toggle = zeros(1,length(IDs));
            fileNames = cell(length(IDs),2);
            for i = 1:length(IDs)
                Xfile = dir("*-typeC-" + IDs{i} + "-x.xy");
                Yfile = dir("*-typeC-" + IDs{i} + "-y.xy");
                if ~(isempty(Xfile) && isempty(Yfile))
                     Toggle(i) = 1;
                     fileNames{i,1} = Xfile.name;
                     fileNames{i,2} = Yfile.name;
                     
                     if IsScalar(i)
                        ScalarItems{end + 1} = PropertyNames{i};
                     else
                        VectorItems{end + 1} = PropertyNames{i};
                        ScalarItems{end + 1} = char(string(PropertyNames{i}) + " Magnitude");
                     end
                end
            end

            ParentDictionary = configureDictionary("string","cell");

            for i = 1:length(IDs)
                ChildDictionary = configureDictionary("string","cell");
                if Toggle(i)
                    ReadData(app,fileNames{i,1},fileNames{i,2})
                    ReFormatData(app)
                    ChildDictionary = insert(ChildDictionary,"Uq",{app.uq});
                    ChildDictionary = insert(ChildDictionary,"Vq",{app.vq});
                    ChildDictionary = insert(ChildDictionary,"Xq",{app.xq});
                    ChildDictionary = insert(ChildDictionary,"Yq",{app.yq});
                    ParentDictionary = insert(ParentDictionary,IDs{i},{ChildDictionary});
                end
            end
            
            
            app.ScalarField.Items = ScalarItems;
            app.VectorField.Items = VectorItems;
            app.ContourData = ParentDictionary;
        
        end
        function StandAlonePlot(app)
            
            % Processing the requested properties for display
            if string(app.VectorField.Value) == "Velocity Vector" ; VF.Name = "V"; end
            if string(app.VectorField.Value) == "None" ; VF.Name ="N"; end
            
            if string(app.ScalarField.Value) == "Static Pressure" ; SF.Name ="p"; end
            if string(app.ScalarField.Value) == "Density" ; SF.Name ="rho"; end
            if string(app.ScalarField.Value) == "Mach Number" ; SF.Name ="M"; end
            if string(app.ScalarField.Value) == "Temperature" ; SF.Name ="T"; end
            if string(app.ScalarField.Value) == "Turbulence Intensity" ; SF.Name ="TI"; end
            if string(app.ScalarField.Value) == "Velocity Vector Magnitude" ; SF.Name ="V"; end
            if string(app.ScalarField.Value) == "None" ; SF.Name ="N"; end
            
            % Create Axis
            ax = app.AxesContour;
            if isempty(ax)
                ax = uiaxes(app.FigurePanel, 'Position', [5, 5, 840, 630]);
            else
                delete(ax.Children) % delte children
            end
            
            % Adjust Axis
            cla(ax,"reset")
            axis(ax, 'tight');
            axis(ax, 'equal');
            
            % Reading the required field back into function
            Data = app.ContourData;
            GraphHandle = app.GraphHandlesC;
            graphSettings = configureDictionary("double","cell"); 
            if  ~(SF.Name == 'N')
                Property = Data(SF.Name);
                Property = Property{1};
                SF.Uq = Property('Uq');
                SF.Uq = SF.Uq{1};
                SF.Vq = Property("Vq");
                SF.Vq = SF.Vq{1};
                SF.Xq = Property("Xq");
                SF.Xq = SF.Xq{1};
                SF.Yq = Property("Yq");
                SF.Yq = SF.Yq{1};
                
                % Plotting the contour
                [c, h] = contourf(ax,SF.Xq, SF.Yq, sqrt(SF.Uq.^2 + SF.Vq.^2));
                h.LevelList = linspace(h.LevelList(1), h.LevelList(end),app.ShadeLevel.Value);
                if app.ContourLines.Value
                    h.LineColor = [0.64 0.64 0.64];
                    CH.LineColor = [0.64 0.64 0.64];
                else
                    h.LineColor = 'none';
                    CH.EdgeColor = "none";
                end
                CH.XData = h.XData;
                CH.YData = h.YData;
                CH.ZData = h.ZData;
                CH.LevelList = h.LevelList;

                GraphHandle = insert(GraphHandle,1,{CH});
                
                % Labels for contour lines
                if app.AddLabelsOnContourLines.Value && app.ContourLines.Value
                    clabel(c,h)
                end
                
                % Color map
                colormap(ax,app.ColorMap.Value) 
                graphSettings = insert(graphSettings,6,{app.ColorMap.Value});
                
                % Color bar
                cb = colorbar(ax);

                cb.Label.String = app.ColorBarString.Value;
                CB.String = app.ColorBarString.Value;

                cb.Label.FontSize = double(app.ColorBarFontSize.Value);
                CB.FontSize = cb.Label.FontSize;

                cb.Location = app.ColorBarLocation.Value;
                CB.Location = cb.Location;

                if app.ColorBarInterpreter.Value
                    cb.Label.Interpreter = "latex";
                    cb.TickLabelInterpreter = "latex";
                    CB.Interpreter = "latex";
                else
                    cb.Label.Interpreter = "none";
                    cb.TickLabelInterpreter = "none";
                    CB.Interpreter = "none";
                end
                graphSettings = insert(graphSettings,5,{CB});

            end

            
            if  ~(VF.Name == 'N')
                hold(ax,"on")
                Property = Data(VF.Name);
                Property = Property{1};
                VF.Uq = Property("Uq");
                VF.Uq = VF.Uq{1};
                VF.Vq = Property("Vq");
                VF.Vq = VF.Vq{1};
                VF.Xq = Property("Xq");
                VF.Xq = VF.Xq{1};
                VF.Yq = Property("Yq");
                VF.Yq = VF.Yq{1};
                
                q = quiver(ax, VF.Xq, VF.Yq, VF.Uq, VF.Vq, app.ScalingFactor.Value, 'filled');
                q.Color = [app.ColorCode_R.Value, app.ColorCode_G.Value, app.ColorCode_B.Value] ;
                q.LineStyle = app.LineStyle.Value;
                q.LineWidth = app.LineWidth.Value;

                Vec.XData = VF.Xq;
                Vec.YData = VF.Yq;
                Vec.UData = VF.Uq;
                Vec.VData = VF.Vq;
                Vec.ScallingFactor = app.ScalingFactor.Value;
                Vec.Color = q.Color;
                Vec.LineStyle = q.LineStyle;
                Vec.LineWidth = q.LineWidth;

                GraphHandle = insert(GraphHandle,2,{Vec});
            end
            hold(ax,"off")
            
            
            % Labels
            tl = title(ax,app.TitleEditField.Value);
            tl.FontSize = app.TitleFontSizeEditField.Value;
            
            Title.String = app.TitleEditField.Value;
            Title.FontSize = tl.FontSize;

            yl = ylabel(ax,app.YLabel.Value);
            yl.FontSize = app.YLabelFontSizeEditField.Value;
            
            YLabeL.String = app.YLabel.Value;
            YLabeL.FontSize = yl.FontSize;

            xl = xlabel(ax,app.XLabel.Value);
            xl.FontSize = app.XLabelFontSize.Value;
            
            XLabeL.String = app.XLabel.Value;
            XLabeL.FontSize = xl.FontSize;
            
            if app.LabelInterpreter.Value 
                xl.Interpreter = "latex";
                yl.Interpreter = "latex"; 
                tl.Interpreter = "latex";
                XLabeL.Interpreter = "latex";
                YLabeL.Interpreter = "latex";
                Title.Interpreter = "latex";
            end


            graphSettings = insert(graphSettings,0,{[1, 1, 1, 0, 1, 1]});
            graphSettings = insert(graphSettings,1,{XLabeL});
            graphSettings = insert(graphSettings,2,{YLabeL});
            graphSettings = insert(graphSettings,3,{Title});
            
            GraphHandle = insert(GraphHandle,0,{graphSettings});
            app.GraphHandlesC = GraphHandle;
            app.AxesContour = ax;


        end
        function TilePlotC(app)
            TileData = app.TileHandlesC;
            TileNumbers = keys(TileData);
            
           
            ax = tiledlayout(app.FigurePanel,app.E_NumRows.Value,app.E_NumCols.Value); 
            
             
            for i = 1 : length(TileNumbers)
                % Start tile
                tile = nexttile(ax,i);
            
                % Retrive data
                GraphHandle = TileData(i);
                GraphHandle = GraphHandle{1};
                GraphContent = keys(GraphHandle);
            
                % Plot Scalar Field
                if ~isempty(find(GraphContent == "1",1))
                    scalar = GraphHandle("1");
                    scalar = scalar{1};
                    contourf(tile,scalar.XData,scalar.YData,scalar.ZData,length(scalar.LevelList),EdgeColor=scalar.EdgeColor);
                    hold(tile,"on")
                end
                
                %Plot Vector Field
                if ~isempty(find(GraphContent == "2",1))
                    vector = GraphHandle("2");
                    vector = vector{1};
                    q = quiver(tile,vector.XData,vector.YData,vector.UData,vector.VData,1,"filled");
                    q.Color = vector.Color ;
                    q.LineStyle = vector.LineStyle;
                    q.LineWidth =vector.LineWidth;
                    hold(tile,"off")
                end
            
                settings = GraphHandle("0");
                settings = settings{1};
                structure = settings("0");
                structure = structure{1};
                
                % X Label
                if structure(1) == true
                    xlSettings = settings("1");
                    xlSettings = xlSettings{1};
                    xl = xlabel(tile,xlSettings.String);
                    xl.FontSize = xlSettings.FontSize;
                    xl.Interpreter = xlSettings.Interpreter;
                    
                end
            
                % Y Label
                if structure(2) == true
                    ylSettings = settings("2");
                    ylSettings = ylSettings{1};
                    yl = ylabel(tile,ylSettings.String);
                    yl.FontSize = ylSettings.FontSize;
                    yl.Interpreter = ylSettings.Interpreter; 
                end
                
                % Title Settings
                if structure(3) == true
                    tlSettings = settings("3");
                    tlSettings = tlSettings{1};
                    tl = title(tile,tlSettings.String);
                    tl.FontSize = tlSettings.FontSize;
                    tl.Interpreter = tlSettings.Interpreter; 
                end
            
                % Color Bar Settings
                if structure(5) == true
                    cbSettings = settings("5");
                    cbSettings = cbSettings{1};
                    ColorMapName = settings("6"); 
                    colormap(tile,ColorMapName{1})
                    cb = colorbar(tile);
                    cb.Label.String = cbSettings.String;
                    cb.Label.FontSize = cbSettings.FontSize;
                    cb.Location = cbSettings.Location; 
                    cb.Label.Interpreter = cbSettings.Interpreter;
                    cb.TickLabelInterpreter = cbSettings.Interpreter;
                end
                axis(tile,"equal")
            
            end
            ax.TileSpacing = "tight";
            app.AxesContour = ax;

        end


        %% Domain Design Section
        function NACA00(app,c,t)
            % Symmetrical NACA 4 Digit Airfoil
            app.Yt = @(x) (t/c.^3)*(1.4845*(c.^3.5)*x.^0.5-0.63*c.^3 *x-1.758*c.^2 *x.^2+1.4215*c*x.^3-0.518*x.^4);
            app.SlopeFunc = @(x) -(t*((879*c^2*x)/250 - (8529*c*x^2)/2000 + (63*c^3)/100 + (259*x^3)/125 - (2969*c^(7/2))/(4000*x^(1/2))))/c^3;
        end
        function [x,y,xdummy] = GeneratePoints(app)
            % This function generates the points on the airfoil surface and the wake,
            % reaching up to some unit chord length away from the trailling edge.
            % WARNING!! Outputs are in unit length therefore a multiplication with chord for
            % scalling appropriately for the case is required
            WakeLength = app.WakeToChordRatio.Value;
            n = app.NumOfPointsonAirfoil.Value;
            k = app.GrowthRate.Value;
            t = app.AirfoilThicknessRatio.Value;
            
            ds = 1 * (1-k)/(1-k^(n-1));
            x = zeros(n,1); % from LE to TE
            for i = 2:(3*n+1)/2
                if i <= n
                    x(i) = x(i-1) + ds * k^(i-2);
                elseif i > n
                    xcoord = x(i-1) + ds*k^(i-2); % wake(i-n)
                    if xcoord >= (1+WakeLength)
                        break
                    else
                        x(i) = xcoord;
                    end
                end
            end
            NACA00(app,1,t);
            yt = app.Yt;
            y = yt(x(1:n));
            y = [y' zeros(1,length(x)-n)];
            xdummy = [-sortrows(x,"descend")' x(2:end)'];
            x = [sortrows(x,"descend")' x(2:end)']; % TE to LE to TE
            y = [ -y(end:-1:1) y(2:end)]; % TE to LE to TE
        end
        function [xp,yp] = SurfaceNormalsEndPoints(app,x)
            slopefunc = app.SlopeFunc;
            yt = app.Yt;
            c = app.AirfoilChordLength.Value;
            dc = app.HeightToChordRatio.Value;
            if 1< x  % Region 5, upper-wake
                x1 = x*c;% in the unit of chord
                y1 = 0; % in the unit of chord
                angle = 0;
                y2 = y1 + (c*dc)*cos(angle);
                x2 = x1 - (c*dc)*sin(angle);
            elseif 0 < x && x <= 1  % Region 4 Upper-surface
                x1 = x*c; 
                y1 = yt(x1);
                m = slopefunc(x1); 
                angle = atan(m);
                y2 = y1 + (c*dc)*cos(angle);
                x2 = x1 - (c*dc)*sin(angle);
            elseif x == 0 % Region 3, Leading Edge
                x1 = 0;
                y1 = 0;
                angle = pi/2;
                y2 = y1 + (c*dc)*cos(angle);
                x2 = x1 - (c*dc)*sin(angle);
            elseif -1 <= x && x < 0 % Region 2, Lower Surface
                x1 = -x*c;
                y1 = -yt(x1);
                m = slopefunc(x1);
                angle =  atan(m);
                y2 = -(-y1 + (c*dc)*cos(angle));
                x2 = x1 - (c*dc)*sin(angle);
            elseif  x < -1 % Region 1,  Lower-Wake
                x1 = -x*c;
                y1 = 0;
                angle = 0;
                y2 = y1 - (c*dc)*cos(angle);
                x2 = x1 + (c*dc)*sin(angle);
            end
            xp = [x1 x2];
            yp = [y1 y2];
        end
        function CreatingRake(app)
            % This function generates the command that creates lines, 
            % It utilizes the point data that "Draw Fomain" button
            % generates
            
            % Chech if the points are generated
            dat = app.P1;
            [N,~] = size(dat);
            if N == 0
                errordlg('No data for the lines have been found. Create data via clicking "Draw Domain" button',"No Points Data Found");
                return
            else
                % Get Row Number
                if app.DrawarectangularregionCheckBox.Value
                   M = app.NumofRowsEditField.Value;
                else
                   M = app.NumberofPointonLineEditField.Value;
                end

                % Generate Commands
                command = cell(N,1);
                for i = 1:length(app.P1(:,1))
                    cmd = "surface/rake-surface " + sprintf('rk%d %.4f %.4f %.4f %.4f %d\n',...
                        i, app.P1(i,1), app.P1(i,2),  app.P2(i,1),app.P2(i,2), M);
                    command{i} = char(cmd);
                end
                % Save Command
                app.TUIcommand = command;
                % Update Command Text Area
                app.TUICommandsTextArea.Value = command;
            end 
        end
        function SaveRakeData(app)
            % Saves the data on the lines for the requested properties
            dat = app.P1;
            [N,~] = size(dat);
            if N == 0
                
            else
                CurrentTextArea = app.TUICommandsTextArea.Value;
                caseName = string(app.CaseName4TUI.Value);
                Link = string(app.SaveFolder4TUI.Value) + "\";
                rk = "";
                for i= 1: N
                    rk= rk+ sprintf('rk%d ',i);
                end
                if app.NodeValuesCheckBox.Value
                    NodeValues = "yes";
                else
                    NodeValues = "no";
                end

                if app.StaticPressureCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-p-x.xy" no no no pressure yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-p-y.xy" no no no pressure yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end

                if app.VelocityVector2DCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-V-x.xy" no no no x-velocity yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-V-y.xy" no no no y-velocity yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end
                
                if app.MachNumberCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-M-x.xy" no no no mach-number yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-M-y.xy" no no no mach-number yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end

                if app.DensityCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-rho-x.xy" no no no density yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-rho-y.xy" no no no density yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end

                if app.TurbulenceIntensityCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-TI-x.xy" no no no turb-intensity yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-TI-y.xy" no no no turb-intensity yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end

                if app.TemperatureCheckBox.Value
                    text = 'plot/plot %s "%s-typeC-T-x.xy" no no no temperature yes 1 0 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                    text = 'plot/plot %s "%s-typeC-T-y.xy" no no no temperature yes 0 1 %s () \n';
                    CurrentTextArea{end + 1} = char(sprintf(text,NodeValues,Link+caseName,rk));
                end
                
                app.TUICommandsTextArea.Value = CurrentTextArea ;
            end

        end
        function DrawDomain(app)
            ax = app.AxesDomainGraph;
            if isempty(ax)
                ax = uiaxes(app.FigurePanel, 'Position', [5, 5, 840, 630]);
            else
                delete(ax.Children) % delte children
            end
            
            if ~(app.DrawarectangularregionCheckBox.Value)
                chord = app.AirfoilChordLength.Value;
                t = app.AirfoilThicknessRatio.Value;
                [x,y,xdummy] = GeneratePoints(app);
                NACA00(app,chord,t);
                cla(ax,'reset')
                x0 = app.Originxcoordinate.Value;
                y0 = app.Originycoordinate.Value;
                plot(ax, x*chord + x0 , y*chord + y0)
                hold(ax,"on")
                app.P1 = zeros(length(xdummy),2);
                app.P2 = zeros(length(xdummy),2);
                for i = 1:length(xdummy)
                     [xp,yp] = SurfaceNormalsEndPoints(app,xdummy(i));
                     plot(ax,xp + x0,yp + y0,"-k",HandleVisibility="off")
                     app.P1(i,1) =xp(1) + x0;
                     app.P2(i,1) =xp(2) + y0;
                     app.P1(i,2) =yp(1) + x0;
                     app.P2(i,2) =yp(2) + y0;
                end
                hold(ax,"off")
                axis(ax,"equal")
                xlim(ax,[ min(app.P2(:,1)) * 0.9, max(app.P2(:,1)) * 1.1])
                xlabel(ax,"x-coordinates, [m]",Interpreter="latex",FontSize=14)
                ylabel(ax,"y-coordinates, [m]",Interpreter="latex",FontSize=14)
            elseif app.DrawarectangularregionCheckBox.Value
                cla(ax,'reset')
                xmin = app.HorizontalminEditField.Value;
                xmax = app.HorizontalmaxEditField.Value;

                ymin = app.VerticalminEditField.Value;
                ymax = app.VerticalmaxEditField.Value;

                xRange = linspace(xmin,xmax,app.NumofColumnsEditField.Value);
                app.P1 = zeros(length(xRange),2);
                app.P2 = zeros(length(xRange),2);
                for i = 1:length(xRange)
                app.P1(i,1) = xRange(i);
                app.P2(i,1) = xRange(i);
                app.P1(i,2) = ymin;
                app.P2(i,2) = ymax;
                plot(ax,[xRange(i) xRange(i)],[ymin ymax],"-b",HandleVisibility="off")
                hold(ax,"on")
                end
                hold(ax,"off")
            end
            grid(ax,"on")
            app.AxesDomainGraph = ax;
        end

       %% Menu Desing
       function OpenSaveMenu(app,tag)
            
            SaveMenu.SaveFigureWindow = uifigure('Visible', 'off');
            SaveMenu.SaveFigureWindow.Position = [500 400 634 254];
            SaveMenu.SaveFigureWindow.Name = 'MATLAB SaveMenu';

            % Create SaveFolderLabel
            SaveMenu.SaveFolderLabel = uilabel(SaveMenu.SaveFigureWindow);
            SaveMenu.SaveFolderLabel.Position = [113 158 70 22];
            SaveMenu.SaveFolderLabel.Text = 'Save Folder';

            % Create SaveFigureFolder
            SaveMenu.SaveFigureFolder = uieditfield(SaveMenu.SaveFigureWindow, 'text');
            SaveMenu.SaveFigureFolder.Editable = 'off';
            SaveMenu.SaveFigureFolder.Placeholder = ' Select Folder via "Get Folder" button >';
            SaveMenu.SaveFigureFolder.Position = [204 158 223 22];

            % Create FileNameEditFieldLabel
            SaveMenu.FileNameEditFieldLabel = uilabel(SaveMenu.SaveFigureWindow);
            SaveMenu.FileNameEditFieldLabel.Position = [113 81 60 22];
            SaveMenu.FileNameEditFieldLabel.Text = 'File Name';

            % Create FileName
            SaveMenu.FileName = uieditfield(SaveMenu.SaveFigureWindow, 'text');
            SaveMenu.FileName.HorizontalAlignment = 'center';
            SaveMenu.FileName.Placeholder = 'eg. Figure1';
            SaveMenu.FileName.Position = [204 81 223 22];

            % Create ExtensionLabel
            SaveMenu.ExtensionLabel = uilabel(SaveMenu.SaveFigureWindow);
            SaveMenu.ExtensionLabel.Position = [113 118 56 22];
            SaveMenu.ExtensionLabel.Text = 'Extension';

            % Create ButtonGroup
            SaveMenu.ButtonGroup = uibuttongroup(SaveMenu.SaveFigureWindow);
            SaveMenu.ButtonGroup.Position = [204 118 223 22];

            % Create epsButton
            SaveMenu.epsButton = uiradiobutton(SaveMenu.ButtonGroup);
            SaveMenu.epsButton.Text = 'eps';
            SaveMenu.epsButton.Position = [21 0 44 22];
            SaveMenu.epsButton.Value = true;

            % Create pngButton
            SaveMenu.pngButton = uiradiobutton(SaveMenu.ButtonGroup);
            SaveMenu.pngButton.Text = 'png';
            SaveMenu.pngButton.Position = [91 1 44 22];

            % Create svgButton
            SaveMenu.svgButton = uiradiobutton(SaveMenu.ButtonGroup);
            SaveMenu.svgButton.Text = 'svg';
            SaveMenu.svgButton.Position = [161 1 41 22];

            % Create SaveButton
            SaveMenu.SaveButton = uibutton(SaveMenu.SaveFigureWindow, 'push', 'ButtonPushedFcn',@(btn,event) SaveFigurePushed(SaveMenu,app,tag));
            SaveMenu.SaveButton.Position = [204 38 223 23];
            SaveMenu.SaveButton.Text = 'Save';

            % Create Button
            SaveMenu.Button = uibutton(SaveMenu.SaveFigureWindow, 'push','ButtonPushedFcn',@(btn,event) GetFolderPushed123(SaveMenu));
            % SaveMenu.Button.ButtonPushedFcn = createCallbackFcn(SaveMenu, @GetFolderPushed, true);
            SaveMenu.Button.FontWeight = 'bold';
            SaveMenu.Button.Position = [439 157 33 23];
            SaveMenu.Button.Text = '...';

            % Show the figure after all components are created
            SaveMenu.SaveFigureWindow.Visible = 'on';
            
            function SaveFigurePushed(SaveMenu,app,tag)
                Path = SaveMenu.SaveFigureFolder.Value;
                fName = SaveMenu.FileName.Value;
                
                ax = [];
                if tag == "1"
                    ax = app.AxesContour;
                elseif tag =="2"
                    ax =app.AxesDomainGraph;
                end

                % Check if there is path assigned
                if isempty(Path)
                    errordlg("There is no path selected for figure to save in to!")
                    return
                end
                
                % Check if there is a file name specified
                if isempty(fName)
                    errordlg("File name is not specified. Figure is not saved!")
                    return
                end
                
                %Check if a figure is displayed
                if isempty(ax)
                    errordlg("The figure has been found to save. Check If a graph or figure is displayed in 'Figure' panel ")
                end

                % Save
                if SaveMenu.svgButton.Value
                    extension = "svg";
                elseif SaveMenu.pngButton.Value
                    exportgraphics(ax, Path + "/" + fName + ".png","Resolution",600)
                elseif SaveMenu.epsButton.Value
                    exportgraphics(ax,Path + "/" + fName + ".eps","Resolution",600,'ContentType', 'vector')
                end


            end
            function GetFolderPushed123(SaveMenu,~)
                folderpath = uigetdir('','Select a fodler to save the image in.');
                disp(folderpath)
                SaveMenu.SaveFigureFolder.Value = folderpath;
                
            end
       end
       

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetFolderButton_2, GetFolderC, GetFolderP
        function GetFolderPushed(app, event)
            folderPath = uigetdir;
            Tag = event.Source.Tag;
            if folderPath ~= 0
                if Tag == "aa"
                    app.DataFolderP.Value = folderPath;
                    ReadProfileData(app)
                elseif Tag == "bb"
                    app.SaveFolder4TUI.Value = folderPath;
                elseif Tag == "cc"
                    app.DataFolderC.Value = folderPath;
                    importContourData(app)
                end
            else
                if Tag == "aa"
                    app.DataFolderP.Value = 'No folder selected.';
                elseif Tag == "bb"
                     app.SaveFolder4TUI.Value = 'No folder selected.' ;
                elseif Tag == "cc"
                    app.DataFolderC.Value = ' No folder seleected.';
                end
            end
        end

        % Callback function
        function SaveFigure(app, event)
            fName = app.FileName.Value;
            if app.epsButton.Value
                exportgraphics(app.UIAxes,Link + "/" + fName + ".eps","Resolution",600,'ContentType', 'vector')
            elseif app.pngButton.Value
                exportgraphics(app.UIAxes,Link + "/" + fName + ".png","Resolution",600)
            end
        end

        % Button pushed function: DrawDomainButton
        function DrawDomainButtonPushed(app, event)
            DrawDomain(app)
        end

        % Button pushed function: WriteCommandsButton
        function WriteCommandsButtonPushed(app, event)
            CreatingRake(app)
            SaveRakeData(app)
        end

        % Button pushed function: DisplayFigureC
        function DisplayFigureCButtonPushed(app, event)
            % to display the figure with current setup
            % to plot tiled layout tall spaces should be filled
            tag = event.Source.Tag;
            if ~isempty(app.TileHandlesC)
                if app.UsetiledlayoutCheckBox.Value
                   if length(keys(app.TileHandlesC)) == app.E_NumCols.Value * app.E_NumRows.Value
                       TilePlotC(app)
                   else
                       StandAlonePlot(app)
                   end
                else
                    StandAlonePlot(app)
                end
            end
        end

        % Value changed function: AddLabelsOnContourLines, 
        % ...and 7 other components
        function UpdateContour(app, event)
            StandAlonePlot(app)
        end

        % Value changed function: UsetiledlayoutCheckBox
        function UsetiledlayoutCheckBoxValueChanged(app, event)
            value = app.UsetiledlayoutCheckBox.Value;
            app.E_TileNumberList.Enable = value;
            app.E_TileNumberListLabel.Enable = value;
            app.E_NumCols.Enable = value;
            app.E_NumRows.Enable = value;
            app.E_NumofRowsNColsLabel.Enable = value;
            app.E_PlaceTile.Enable = value;
            app.E_TileNumberDropDown.Enable =value;
            app.E_TileNumberDropDownLabel.Enable = value;
            
            r = app.E_NumRows.Value;
            c = app.E_NumCols.Value;
            list = cell(1,r*c);
            for i = 1: r*c
                list{i} = char(sprintf("%dth Tile",i ));
            end

            app.E_TileNumberList.Items = list;
            app.E_TileNumberDropDown.Items = list;
        end

        % Button pushed function: E_PlaceTile
        function PlaceOnTileButton(app, event)
            tag = event.Source.Tag;
            if tag == "c"
                currentTileData = app.TileHandlesC;
                currentTileData = insert(currentTileData,app.E_TileNumberList.ValueIndex,{app.GraphHandlesC});
                app.TileHandlesC = currentTileData;
            elseif tag == "p"
            end
        end

        % Value changed function: E_NumCols, E_NumRows
        function UpdateTileNumberList(app, event)
            r = app.E_NumRows.Value;
            c = app.E_NumCols.Value;
            list = cell(1,r*c);
            for i = 1: r*c
                list{i} = char(sprintf("%dth Tile",i ));
            end

            app.E_TileNumberList.Items = list;
            app.E_TileNumberDropDown.Items = list;
        end

        % Button pushed function: ClearFigureC
        function ClearFigureCButtonPushed(app, event)
            app.TileHandlesC = [];
            app.GraphHandlesC = [];
        end

        % Menu selected function: SaveContourMenu, SaveDomainImage, 
        % ...and 1 other component
        function SaveMenuSelected(app, event)
            
            tag = event.Source.Tag; % The tag of the menu which is initiated
            OpenSaveMenu(app,tag)


        end

        % Menu selected function: DBDonAirfoilMenu
        function DBDonAirfoilMenuSelected(app, event)
            patchapp;
        end

        % Value changed function: DrawarectangularregionCheckBox
        function DrawarectangularregionCheckBoxValueChanged(app, event)
            value = app.DrawarectangularregionCheckBox.Value;
            app.HorizontalmaxEditField.Enable = value;
            app.HorizontalmaxEditFieldLabel.Enable = value;
            
            app.HorizontalminEditField.Enable = value;
            app.HorizontalminEditFieldLabel.Enable = value;

            app.VerticalmaxEditField.Enable = value;
            app.VerticalmaxEditFieldLabel.Enable = value;

            app.VerticalminEditField.Enable = value;
            app.VerticalminEditFieldLabel.Enable = value;
            
            app.NumofColumnsEditField.Enable = value;
            app.NumofColumnsEditFieldLabel.Enable = value;

            app.NumofRowsEditField.Enable = value;
            app.NumofRowsEditFieldLabel.Enable = value;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.SaveContourMenu = uimenu(app.ExportMenu);
            app.SaveContourMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveContourMenu.Text = 'Save Contour';
            app.SaveContourMenu.Tag = '1';
            app.SaveProfileMenu = uimenu(app.ExportMenu);
            app.SaveProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveProfileMenu.Text = 'Save Profile';
            app.SaveProfileMenu.Tag = '3';
            app.SaveDomainImage = uimenu(app.ExportMenu);
            app.SaveDomainImage.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveDomainImage.Text = 'Save Domain Image';
            app.SaveDomainImage.Tag = '2';
            app.PatchingMenu = uimenu(app.UIFigure);
            app.PatchingMenu.Text = 'Patching';
            app.DBDonAirfoilMenu = uimenu(app.PatchingMenu);
            app.DBDonAirfoilMenu.MenuSelectedFcn = createCallbackFcn(app, @DBDonAirfoilMenuSelected, true);
            app.DBDonAirfoilMenu.Text = 'DBD on Airfoil';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurvePlottingTab = uitab(app.TabGroup);
            app.CurvePlottingTab.Title = 'Curve Plotting';
            app.ReadDataPanelProfilePlottig = uipanel(app.CurvePlottingTab);
            app.ReadDataPanelProfilePlottig.Title = 'Read Data';
            app.DataFolderEditFieldLabel_2 = uilabel(app.ReadDataPanelProfilePlottig);
            app.DataFolderEditFieldLabel_2.Text = 'Data Folder';
            app.DataFolderP = uieditfield(app.ReadDataPanelProfilePlottig, 'text');
            app.DataFolderP.Tag = '4';
            app.DataFolderP.Placeholder = ' Select a folder via "Get Folder" button >';
            app.GetFolderP = uibutton(app.ReadDataPanelProfilePlottig, 'push');
            app.GetFolderP.ButtonPushedFcn = createCallbackFcn(app, @GetFolderPushed, true);
            app.GetFolderP.Tag = 'aa';
            app.GetFolderP.Text = 'Get Folder';
            app.FoundQuantitiesDropDownLabel = uilabel(app.CurvePlottingTab);
            app.FoundQuantitiesDropDownLabel.Text = 'Found Quantities';
            app.FoundQuantities = uidropdown(app.CurvePlottingTab);
            app.FoundQuantities.Items = {};
            app.FoundQuantities.Value = {};
            app.AvailableDataLabel = uilabel(app.CurvePlottingTab);
            app.AvailableDataLabel.Text = 'Available Data';
            app.DataList = uilistbox(app.CurvePlottingTab);
            app.DataList.Items = {};
            app.DataList.Value = {};
            app.Button = uibutton(app.CurvePlottingTab, 'push');
            app.ContourPlottingTab = uitab(app.TabGroup);
            app.ContourPlottingTab.Title = 'Contour Plotting';
            app.ReadDataPanel = uipanel(app.ContourPlottingTab);
            app.ReadDataPanel.Title = 'Read Data';
            app.GetFolderC = uibutton(app.ReadDataPanel, 'push');
            app.GetFolderC.ButtonPushedFcn = createCallbackFcn(app, @GetFolderPushed, true);
            app.GetFolderC.Tag = 'cc';
            app.GetFolderC.Text = 'Get Folder';
            app.DataFolderEditFieldLabel = uilabel(app.ReadDataPanel);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderC = uieditfield(app.ReadDataPanel, 'text');
            app.DataFolderC.Editable = 'off';
            app.DataFolderC.Placeholder = ' Select Folder via "Get Folder" button >';
            app.ScalarFieldsDropDownLabel = uilabel(app.ContourPlottingTab);
            app.ScalarFieldsDropDownLabel.Text = 'Scalar Fields';
            app.ScalarField = uidropdown(app.ContourPlottingTab);
            app.ScalarField.Items = {};
            app.ScalarField.Value = {};
            app.VectorFieldsDropDownLabel = uilabel(app.ContourPlottingTab);
            app.VectorFieldsDropDownLabel.Text = 'Vector Fields';
            app.VectorField = uidropdown(app.ContourPlottingTab);
            app.VectorField.Items = {};
            app.VectorField.Value = {};
            app.DisplayFigureC = uibutton(app.ContourPlottingTab, 'push');
            app.DisplayFigureC.ButtonPushedFcn = createCallbackFcn(app, @DisplayFigureCButtonPushed, true);
            app.DisplayFigureC.Text = 'Display Figure';
            app.ClearFigureC = uibutton(app.ContourPlottingTab, 'push');
            app.ClearFigureC.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureCButtonPushed, true);
            app.ClearFigureC.Text = 'Clear Figure';
            app.ScalarFieldSettingsPanel = uipanel(app.ContourPlottingTab);
            app.ScalarFieldSettingsPanel.Title = 'Scalar Field Settings';
            app.ColorMapDropDownLabel = uilabel(app.ScalarFieldSettingsPanel);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColorMap = uidropdown(app.ScalarFieldSettingsPanel);
            app.ColorMap.Items = {'jet', 'turbo', 'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'sky', 'abyss'};
            app.ColorMap.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ColorMap.Value = 'jet';
            app.ShadeLevelEditFieldLabel = uilabel(app.ScalarFieldSettingsPanel);
            app.ShadeLevelEditFieldLabel.Text = 'Shade Level';
            app.ShadeLevel = uieditfield(app.ScalarFieldSettingsPanel, 'numeric');
            app.ShadeLevel.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ShadeLevel.Value = 256;
            app.ColorBarStringEditFieldLabel = uilabel(app.ScalarFieldSettingsPanel);
            app.ColorBarStringEditFieldLabel.Text = 'Color Bar String';
            app.ColorBarString = uieditfield(app.ScalarFieldSettingsPanel, 'text');
            app.ColorBarString.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ColorBarString.Placeholder = 'Static Pressure, [Pa]';
            app.ColorBarFontSizeEditFieldLabel = uilabel(app.ScalarFieldSettingsPanel);
            app.ColorBarFontSizeEditFieldLabel.Text = 'Color Bar Font Size';
            app.ColorBarFontSize = uieditfield(app.ScalarFieldSettingsPanel, 'numeric');
            app.ColorBarFontSize.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ColorBarFontSize.Value = 14;
            app.ColorBarInterpreter = uicheckbox(app.ScalarFieldSettingsPanel);
            app.ColorBarInterpreter.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ColorBarInterpreter.Text = 'Set LaTeX as interpreter';
            app.ColorBarInterpreter.Value = true;
            app.ColorBarLocationDropDownLabel = uilabel(app.ScalarFieldSettingsPanel);
            app.ColorBarLocationDropDownLabel.Text = 'Color Bar Location';
            app.ColorBarLocation = uidropdown(app.ScalarFieldSettingsPanel);
            app.ColorBarLocation.Items = {'north', 'south', 'east', 'west', 'northoutside', 'southoutside', 'eastoutside', 'westoutside'};
            app.ColorBarLocation.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ColorBarLocation.Value = 'north';
            app.AddLabelsOnContourLines = uicheckbox(app.ScalarFieldSettingsPanel);
            app.AddLabelsOnContourLines.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.AddLabelsOnContourLines.Text = 'Add labels on contour lines';
            app.ContourLines = uicheckbox(app.ScalarFieldSettingsPanel);
            app.ContourLines.ValueChangedFcn = createCallbackFcn(app, @UpdateContour, true);
            app.ContourLines.Text = 'Turn on contour lines';
            app.AxisLabelsPanel = uipanel(app.ContourPlottingTab);
            app.AxisLabelsPanel.Title = ' Axis Labels';
            app.XLabelEditFieldLabel = uilabel(app.AxisLabelsPanel);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabel = uieditfield(app.AxisLabelsPanel, 'text');
            app.XLabel.Placeholder = 'x - coordinates , [m]';
            app.XLabelFontSizeEditFieldLabel = uilabel(app.AxisLabelsPanel);
            app.XLabelFontSizeEditFieldLabel.Text = 'X Label Font Size';
            app.XLabelFontSize = uieditfield(app.AxisLabelsPanel, 'numeric');
            app.XLabelFontSize.Value = 14;
            app.LabelInterpreter = uicheckbox(app.AxisLabelsPanel);
            app.LabelInterpreter.Text = 'Set LaTeX as interpreter';
            app.LabelInterpreter.Value = true;
            app.YLabelEditFieldLabel = uilabel(app.AxisLabelsPanel);
            app.YLabelEditFieldLabel.Text = 'Y Label';
            app.YLabel = uieditfield(app.AxisLabelsPanel, 'text');
            app.YLabel.Placeholder = 'y - coordinates, [m]';
            app.XLabelFontSizeEditFieldLabel_2 = uilabel(app.AxisLabelsPanel);
            app.XLabelFontSizeEditFieldLabel_2.Text = 'Y Label Font Size';
            app.YLabelFontSizeEditField = uieditfield(app.AxisLabelsPanel, 'numeric');
            app.YLabelFontSizeEditField.Value = 14;
            app.E_TileNumberDropDownLabel = uilabel(app.AxisLabelsPanel);
            app.E_TileNumberDropDownLabel.Enable = 'off';
            app.E_TileNumberDropDownLabel.Text = 'Tile Number';
            app.E_TileNumberDropDown = uidropdown(app.AxisLabelsPanel);
            app.E_TileNumberDropDown.Items = {};
            app.E_TileNumberDropDown.Enable = 'off';
            app.E_TileNumberDropDown.Value = {};
            app.TitleEditFieldLabel = uilabel(app.AxisLabelsPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.AxisLabelsPanel, 'text');
            app.TitleEditField.Placeholder = 'NACA Airfoil at $6^\circ$ AOA';
            app.TitleFontSizeEditFieldLabel = uilabel(app.AxisLabelsPanel);
            app.TitleFontSizeEditFieldLabel.Text = 'Title Font Size';
            app.TitleFontSizeEditField = uieditfield(app.AxisLabelsPanel, 'numeric');
            app.TitleFontSizeEditField.Value = 20;
            app.VectorFieldSettingsPanel = uipanel(app.ContourPlottingTab);
            app.VectorFieldSettingsPanel.Title = 'Vector Field Settings';
            app.ScalingFactorEditFieldLabel = uilabel(app.VectorFieldSettingsPanel);
            app.ScalingFactorEditFieldLabel.Text = 'Scaling Factor';
            app.ScalingFactor = uieditfield(app.VectorFieldSettingsPanel, 'numeric');
            app.ScalingFactor.Value = 0.9;
            app.LineWidthEditFieldLabel = uilabel(app.VectorFieldSettingsPanel);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidth = uieditfield(app.VectorFieldSettingsPanel, 'numeric');
            app.LineWidth.Value = 1.2;
            app.LineStyleDropDownLabel = uilabel(app.VectorFieldSettingsPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyle = uidropdown(app.VectorFieldSettingsPanel);
            app.LineStyle.Items = {'-', '--', '-.', ':'};
            app.LineStyle.Value = '-';
            app.ColorRGBLabel = uilabel(app.VectorFieldSettingsPanel);
            app.ColorRGBLabel.Text = 'Color RGB';
            app.ColorCode_R = uieditfield(app.VectorFieldSettingsPanel, 'numeric');
            app.ColorCode_R.Limits = [0 1];
            app.ColorCode_G = uieditfield(app.VectorFieldSettingsPanel, 'numeric');
            app.ColorCode_G.Limits = [0 1];
            app.ColorCode_B = uieditfield(app.VectorFieldSettingsPanel, 'numeric');
            app.ColorCode_B.Limits = [0 1];
            app.TiledLayoutSettings = uipanel(app.ContourPlottingTab);
            app.E_NumofRowsNColsLabel = uilabel(app.TiledLayoutSettings);
            app.E_NumofRowsNColsLabel.Enable = 'off';
            app.E_NumofRowsNColsLabel.Text = 'Number of rows  & columns';
            app.E_NumRows = uieditfield(app.TiledLayoutSettings, 'numeric');
            app.E_NumRows.ValueChangedFcn = createCallbackFcn(app, @UpdateTileNumberList, true);
            app.E_NumRows.Enable = 'off';
            app.E_NumRows.Value = 1;
            app.E_NumCols = uieditfield(app.TiledLayoutSettings, 'numeric');
            app.E_NumCols.ValueChangedFcn = createCallbackFcn(app, @UpdateTileNumberList, true);
            app.E_NumCols.Enable = 'off';
            app.E_NumCols.Value = 2;
            app.E_TileNumberListLabel = uilabel(app.TiledLayoutSettings);
            app.E_TileNumberListLabel.Enable = 'off';
            app.E_TileNumberListLabel.Text = 'Tile Number for plotting';
            app.E_TileNumberList = uidropdown(app.TiledLayoutSettings);
            app.E_TileNumberList.Enable = 'off';
            app.UsetiledlayoutCheckBox = uicheckbox(app.TiledLayoutSettings);
            app.UsetiledlayoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsetiledlayoutCheckBoxValueChanged, true);
            app.UsetiledlayoutCheckBox.Text = 'Use tiled layout';
            app.E_PlaceTile = uibutton(app.ContourPlottingTab, 'push');
            app.E_PlaceTile.ButtonPushedFcn = createCallbackFcn(app, @PlaceOnTileButton, true);
            app.E_PlaceTile.Tag = 'c';
            app.E_PlaceTile.Enable = 'off';
            app.E_PlaceTile.Text = 'Place on tile';
            app.TUICommandTab = uitab(app.TabGroup);
            app.TUICommandTab.Title = 'TUI Command';
            app.DomainParametersforNACA4digitairfoilPanel = uipanel(app.TUICommandTab);
            app.DomainParametersforNACA4digitairfoilPanel.Title = 'Domain Parameters for NACA 4 digit airfoil';
            app.AirfoilCamberRatioEditFieldLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilCamberRatioEditFieldLabel.Enable = 'off';
            app.AirfoilCamberRatioEditFieldLabel.Text = 'Airfoil Camber Ratio';
            app.AirfoilCamberRatio = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.AirfoilCamberRatio.Enable = 'off';
            app.AirfoilChordLengthmLabel_4 = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilChordLengthmLabel_4.Text = 'Growth Rate';
            app.GrowthRate = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.GrowthRate.Value = 1.05;
            app.AirfoilMaxCamberLocationEditFieldLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilMaxCamberLocationEditFieldLabel.Enable = 'off';
            app.AirfoilMaxCamberLocationEditFieldLabel.Text = 'Airfoil Max Camber Location';
            app.AirfoilMaxCamberLocation = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.AirfoilMaxCamberLocation.Enable = 'off';
            app.AirfoilChordLengthmLabel_5 = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilChordLengthmLabel_5.Text = ' Number of Points on Airfoil';
            app.NumOfPointsonAirfoil = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.NumOfPointsonAirfoil.Value = 101;
            app.AirfoilThicknessRatioEditFieldLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilThicknessRatioEditFieldLabel.Text = 'Airfoil Thickness Ratio';
            app.AirfoilThicknessRatio = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.AirfoilThicknessRatio.Value = 0.12;
            app.NumberofPointonLineEditFieldLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.NumberofPointonLineEditFieldLabel.Text = 'Number of Point on Line';
            app.NumberofPointonLineEditField = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.NumberofPointonLineEditField.Value = 100;
            app.AirfoilChordLengthmLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilChordLengthmLabel.Text = 'Airfoil Chord Length (m)';
            app.AirfoilChordLength = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.AirfoilChordLength.Value = 0.1;
            app.AirfoilChordLengthmLabel_2 = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilChordLengthmLabel_2.Text = 'Height-to-Chord Ratio';
            app.HeightToChordRatio = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.HeightToChordRatio.Value = 0.5;
            app.AirfoilChordLengthmLabel_3 = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.AirfoilChordLengthmLabel_3.Text = 'Wake-to-Chord Ratio';
            app.WakeToChordRatio = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.WakeToChordRatio.Value = 0.5;
            app.OriginxcoordinateLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.OriginxcoordinateLabel.Text = 'Leading edge x - coordinate';
            app.Originxcoordinate = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.LeadingedgeycoordinateLabel = uilabel(app.DomainParametersforNACA4digitairfoilPanel);
            app.LeadingedgeycoordinateLabel.Text = 'Leading edge  y - coordinate';
            app.Originycoordinate = uieditfield(app.DomainParametersforNACA4digitairfoilPanel, 'numeric');
            app.DataOptionsPanel = uipanel(app.TUICommandTab);
            app.DataOptionsPanel.Title = 'Data Options';
            app.StaticPressureCheckBox = uicheckbox(app.DataOptionsPanel);
            app.StaticPressureCheckBox.Text = 'Static Pressure';
            app.StaticPressureCheckBox.Value = true;
            app.VelocityVector2DCheckBox = uicheckbox(app.DataOptionsPanel);
            app.VelocityVector2DCheckBox.Text = 'Velocity Vector (2D)';
            app.VelocityVector2DCheckBox.Value = true;
            app.DensityCheckBox = uicheckbox(app.DataOptionsPanel);
            app.DensityCheckBox.Text = 'Density';
            app.TurbulenceIntensityCheckBox = uicheckbox(app.DataOptionsPanel);
            app.TurbulenceIntensityCheckBox.Text = 'Turbulence Intensity';
            app.MachNumberCheckBox = uicheckbox(app.DataOptionsPanel);
            app.MachNumberCheckBox.Text = 'Mach Number';
            app.TemperatureCheckBox = uicheckbox(app.DataOptionsPanel);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.SavingOptionsPanel = uipanel(app.TUICommandTab);
            app.SavingOptionsPanel.Title = 'Saving Options';
            app.SaveFolderEditFieldLabel = uilabel(app.SavingOptionsPanel);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolder4TUI = uieditfield(app.SavingOptionsPanel, 'text');
            app.SaveFolder4TUI.Editable = 'off';
            app.SaveFolder4TUI.Placeholder = 'Select a folder >';
            app.CaseNameEditFieldLabel = uilabel(app.SavingOptionsPanel);
            app.CaseNameEditFieldLabel.Text = 'Case Name';
            app.CaseName4TUI = uieditfield(app.SavingOptionsPanel, 'text');
            app.CaseName4TUI.Placeholder = 'eg. case1-15AOA';
            app.NodeValuesCheckBox = uicheckbox(app.SavingOptionsPanel);
            app.NodeValuesCheckBox.Text = 'Node Values';
            app.NodeValuesCheckBox.Value = true;
            app.GetFolderButton_2 = uibutton(app.SavingOptionsPanel, 'push');
            app.GetFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetFolderPushed, true);
            app.GetFolderButton_2.Tag = 'bb';
            app.GetFolderButton_2.Text = 'Get Folder';
            app.TUICommandsTextAreaLabel = uilabel(app.TUICommandTab);
            app.TUICommandsTextAreaLabel.Text = ' TUI Commands  ';
            app.TUICommandsTextArea = uitextarea(app.TUICommandTab);
            app.DomainParametersforRectangularAreaPanel = uipanel(app.TUICommandTab);
            app.DomainParametersforRectangularAreaPanel.Title = 'Domain Parameters for Rectangular Area';
            app.HorizontalmaxEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.HorizontalmaxEditFieldLabel.Enable = 'off';
            app.HorizontalmaxEditFieldLabel.Text = ' Horizontal max';
            app.HorizontalmaxEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.HorizontalmaxEditField.Enable = 'off';
            app.HorizontalmaxEditField.Value = 0.1;
            app.HorizontalminEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.HorizontalminEditFieldLabel.Enable = 'off';
            app.HorizontalminEditFieldLabel.Text = ' Horizontal min';
            app.HorizontalminEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.HorizontalminEditField.Enable = 'off';
            app.VerticalmaxEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.VerticalmaxEditFieldLabel.Enable = 'off';
            app.VerticalmaxEditFieldLabel.Text = ' Vertical max';
            app.VerticalmaxEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.VerticalmaxEditField.Enable = 'off';
            app.VerticalmaxEditField.Value = 0.3;
            app.VerticalminEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.VerticalminEditFieldLabel.Enable = 'off';
            app.VerticalminEditFieldLabel.Text = ' Vertical min';
            app.VerticalminEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.VerticalminEditField.Enable = 'off';
            app.NumofRowsEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.NumofRowsEditFieldLabel.Enable = 'off';
            app.NumofRowsEditFieldLabel.Text = 'Num of Rows';
            app.NumofRowsEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.NumofRowsEditField.Enable = 'off';
            app.NumofRowsEditField.Value = 100;
            app.NumofColumnsEditFieldLabel = uilabel(app.DomainParametersforRectangularAreaPanel);
            app.NumofColumnsEditFieldLabel.Enable = 'off';
            app.NumofColumnsEditFieldLabel.Text = 'Num of Columns';
            app.NumofColumnsEditField = uieditfield(app.DomainParametersforRectangularAreaPanel, 'numeric');
            app.NumofColumnsEditField.Enable = 'off';
            app.NumofColumnsEditField.Value = 100;
            app.DrawarectangularregionCheckBox = uicheckbox(app.DomainParametersforRectangularAreaPanel);
            app.DrawarectangularregionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawarectangularregionCheckBoxValueChanged, true);
            app.DrawarectangularregionCheckBox.Text = 'Draw a rectangular region';
            app.ButtonGroup = uibuttongroup(app.TUICommandTab);
            app.DrawDomainButton = uibutton(app.ButtonGroup, 'push');
            app.DrawDomainButton.ButtonPushedFcn = createCallbackFcn(app, @DrawDomainButtonPushed, true);
            app.DrawDomainButton.Text = 'Draw Domain';
            app.WriteCommandsButton = uibutton(app.ButtonGroup, 'push');
            app.WriteCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @WriteCommandsButtonPushed, true);
            app.WriteCommandsButton.Text = 'Write Commands';
            app.FigurePanel = uipanel(app.UIFigure);
            app.FigurePanel.Title = 'Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end