classdef patchapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlottherectanglesButton         matlab.ui.control.Button
        ChordLengthmEditField           matlab.ui.control.NumericEditField
        ChordLengthmEditFieldLabel      matlab.ui.control.Label
        GenerateRectanglesButton        matlab.ui.control.Button
        xandycoordinatesoftheverticesTextArea  matlab.ui.control.TextArea
        xandycoordinatesoftheverticesLabel  matlab.ui.control.Label
        LocationsxcEditField            matlab.ui.control.EditField
        LocationsxcEditFieldLabel       matlab.ui.control.Label
        NACA4DigitThicknessRatioEditField  matlab.ui.control.NumericEditField
        NACA4DigitThicknessRatioLabel   matlab.ui.control.Label
        WidthofPlasmaRegionmmEditField  matlab.ui.control.NumericEditField
        WidthofPlasmaRegionmmEditFieldLabel  matlab.ui.control.Label
        HeightofPlasmaRegionmmEditField  matlab.ui.control.NumericEditField
        HeightofPlasmaRegionmmEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X
        Y
        Yt
        SlopeFunc
    end
    
    methods (Access = private)
        function GenerateRectangles(app)
                chord = app.ChordLengthmEditField.Value;
                thicknessRatio = app.NACA4DigitThicknessRatioEditField.Value/100;
                height = app.HeightofPlasmaRegionmmEditField.Value * 1e-3;
                width = app.WidthofPlasmaRegionmmEditField.Value * 1e-3;
                NACA00(app,chord,thicknessRatio)
                yt = app.Yt;
                slope_func = app.SlopeFunc;

                Locations = app.LocationsxcEditField.Value;
                Locations = regexprep(Locations,'[^0-9.,]', '');
                Locations = split(Locations,",");
                for n =1 :length(Locations)
                    Locations{n} = double(string(Locations{n}));
                end
                x = zeros(length(Locations),4);
                y = zeros(length(Locations),4);
                TextArea = cell(length(Locations),1);
                TextArea{1} = 'Location     X-Coordinates    Y-Coordinates';
                for i = 2: length(Locations)+1
                    XX = Locations{i-1};
                    x1 = XX * chord;
                    y1 = yt(x1);
                    slope = slope_func(x1);
                    theta = atan(slope);
                    x2 = x1 + cos(theta)*width;   
                    y2 = y1 + sin(theta)*width;
                    x3 = x2 - sin(theta)*height;
                    y3 = y2 + cos(theta)*height;
                    y4 = y1 + cos(theta)*height;
                    x4 = x1 - sin(theta)*height;
                    x(i-1,:) = [x1,x2,x3,x4];
                    y(i-1,:) = [y1,y2,y3,y4];
                    
                    msg = sprintf("%d     [%f,%f,%f,%f]     [%f,%f,%f,%f]",i-1,x1,x2,x3,x4,y1,y2,y3,y4);
                    TextArea{i} = char(msg);
                end 
                app.X = x;
                app.Y = y;
                app.xandycoordinatesoftheverticesTextArea.Value = TextArea;
            end
        function PlotThePatches(app)
            chord = app.ChordLengthmEditField.Value;
            thicknessRatio = app.NACA4DigitThicknessRatioEditField.Value/100;
            NACA00(app,chord,thicknessRatio)
            yt = app.Yt;  
            x = app.X;
            y = app.Y;
            xloc = linspace(0,chord,250);
            thickness = yt(xloc);
            
            plot(app.UIAxes,xloc,thickness, 'LineWidth', 1.5)
            hold(app.UIAxes,"on")
            for i= 1:length(x(:,1))
                fill(app.UIAxes,x(i,:), y(i,:),"m");
            end
            hold(app.UIAxes,"off")
            axis(app.UIAxes,'equal')
            ylabel(app.UIAxes,"y-coordinates, [m]",FontSize=13,Interpreter="latex")
            xlabel(app.UIAxes,"x-coordinates, [m]",FontSize=13,Interpreter="latex")

        end
        function NACA00(app,c,t)
            % Symmetrical NACA 4 Digit Airfoil
            app.Yt = @(x) (t/c.^3)*(1.4845*(c.^3.5)*x.^0.5-0.63*c.^3 *x-1.758*c.^2 *x.^2+1.4215*c*x.^3-0.518*x.^4);
            app.SlopeFunc = @(x) -(t*((879*c^2*x)/250 - (8529*c*x^2)/2000 + (63*c^3)/100 + (259*x^3)/125 - (2969*c^(7/2))/(4000*x^(1/2))))/c^3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateRectanglesButton
        function GenerateRectanglesButtonPushed(app, event)
            GenerateRectangles(app)
        end

        % Button pushed function: PlottherectanglesButton
        function PlottherectanglesButtonPushed(app, event)
            PlotThePatches(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HeightofPlasmaRegionmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofPlasmaRegionmmEditFieldLabel.Text = 'Height of Plasma Region (mm)';
            app.HeightofPlasmaRegionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofPlasmaRegionmmEditField.Value = 0.3;
            app.WidthofPlasmaRegionmmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthofPlasmaRegionmmEditFieldLabel.Text = 'Width of Plasma Region (mm)';
            app.WidthofPlasmaRegionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthofPlasmaRegionmmEditField.Value = 3;
            app.NACA4DigitThicknessRatioLabel = uilabel(app.UIFigure);
            app.NACA4DigitThicknessRatioLabel.Text = 'NACA 4-Digit Thickness Ratio  (%)';
            app.NACA4DigitThicknessRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.NACA4DigitThicknessRatioEditField.Value = 12;
            app.LocationsxcEditFieldLabel = uilabel(app.UIFigure);
            app.LocationsxcEditFieldLabel.Text = 'Locations (x/c)';
            app.LocationsxcEditField = uieditfield(app.UIFigure, 'text');
            app.LocationsxcEditField.Placeholder = 'e.g. 0.15, 0.20, 0.30';
            app.xandycoordinatesoftheverticesLabel = uilabel(app.UIFigure);
            app.xandycoordinatesoftheverticesLabel.Text = 'x and y coordinates of the vertices:';
            app.xandycoordinatesoftheverticesTextArea = uitextarea(app.UIFigure);
            app.GenerateRectanglesButton = uibutton(app.UIFigure, 'push');
            app.GenerateRectanglesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRectanglesButtonPushed, true);
            app.GenerateRectanglesButton.Text = 'Generate Rectangles';
            app.ChordLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.ChordLengthmEditFieldLabel.Text = 'Chord Length (m)';
            app.ChordLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChordLengthmEditField.Value = 0.1;
            app.PlottherectanglesButton = uibutton(app.UIFigure, 'push');
            app.PlottherectanglesButton.ButtonPushedFcn = createCallbackFcn(app, @PlottherectanglesButtonPushed, true);
            app.PlottherectanglesButton.Text = 'Plot the rectangles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patchapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end